classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        ChooseImageButton     matlab.ui.control.Button
        Image_2               matlab.ui.control.Image
        OriginalImageLabel    matlab.ui.control.Label
        IdentifiedCoinsLabel  matlab.ui.control.Label
        PenniesLabel          matlab.ui.control.Label
        NicklesLabel          matlab.ui.control.Label
        DimesLabel            matlab.ui.control.Label
        QuartersLabel         matlab.ui.control.Label
        TotalLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg'; '*.jpeg'; '*.png'; '*.bmp'}, "Choose Image");
            imageLocation = strcat(pathname, filename);
            myImage = imread(imageLocation);
            
            imshow(myImage, 'parent', uiaxes(app.UIFigure, 'position', [19 70 610 430]));
            
            myImage = rgb2gray(myImage);
            myImage = myImage - 80;
            
            [c, r] = imfindcircles(myImage, [200 1300], 'ObjectPolarity', 'dark', 'Sensitivity', 0.98);
            cTemp = c;
            rTemp = r;
%             viscircles(, c, r);
            
            B=myImage;
            for i=1:length(r)
                theta=0:1:360;
                rTemp=round(c(i,1) + r(i)*sin(theta));
                cTemp=round(c(i,2) + r(i)*cos(theta));
                    for j=1:length(rTemp)
                        B(cTemp(j),rTemp(j))=255;
                    end
            end
            drawnow
            imwrite(B,'A.jpg');
            
            imshow(B, 'parent', uiaxes(app.UIFigure, 'position', [639 70 610 430]));
            
            ratios = [0.9083, 0.6500, 0.7156, 0.8953, 0.7259, 0.7993, 1/0.9083, 1/0.6500, 1/0.7156, 1/0.8953, 1/0.7259, 1/0.7993];
            coins = ["Penny", "Quarter", "Quarter", "Quarter", "Nickle", "Nickle", "Dime", "Dime", "Penny", "Nickle", "Dime", "Penny"];
            pennies = 0;
            nickles = 0;
            dimes = 0;
            quarters = 0;
            
            
            if max(size(r)) > 1
                for i = 2:length(r)
                    val = r(1) / r(i);
                    for j = 1:length(ratios)
                        ppppp = abs(val/ratios(j));
                        if abs((val/ratios(j))-1) < 0.03
                            if strcmp(coins(j), "Penny")
                                pennies = pennies + 1;
                            elseif strcmp(coins(j), "Nickle")
                                nickles = nickles + 1;
                            elseif strcmp(coins(j), "Dime")
                                dimes = dimes + 1;
                            elseif strcmp(coins(j), "Quarter")
                                quarters = quarters + 1;
                            end
                            disp(coins(j))
                            disp(ratios(j))
                        end      
                    end
                end
            end
            
            total = pennies*.01 + nickles*.05 + dimes*.1 + quarters*.25;
            
            app.PenniesLabel.Text = sprintf("Pennies: %d", pennies);
            app.NicklesLabel.Text = sprintf("Nickles: %d", nickles);
            app.DimesLabel.Text = sprintf("Dimes: %d", dimes);
            app.QuartersLabel.Text = sprintf("Quarters: %d", quarters);
            app.TotalLabel.Text = sprintf("Total: $%.2f", total);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.Image_2 = uiimage(app.UIFigure);
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.IdentifiedCoinsLabel = uilabel(app.UIFigure);
            app.IdentifiedCoinsLabel.Text = 'Identified Coins';
            app.PenniesLabel = uilabel(app.UIFigure);
            app.PenniesLabel.Text = 'Pennies:';
            app.NicklesLabel = uilabel(app.UIFigure);
            app.NicklesLabel.Text = 'Nickles:';
            app.DimesLabel = uilabel(app.UIFigure);
            app.DimesLabel.Text = 'Dimes:';
            app.QuartersLabel = uilabel(app.UIFigure);
            app.QuartersLabel.Text = 'Quarters:';
            app.TotalLabel = uilabel(app.UIFigure);
            app.TotalLabel.Text = 'Total: $';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end